classdef neuroAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        WorkFileMenu                matlab.ui.container.Menu
        OutDirMenu                  matlab.ui.container.Menu
        OutDirEditField             matlab.ui.control.EditField
        OutDirEditFieldLabel        matlab.ui.control.Label
        GoSwitch                    matlab.ui.control.ToggleSwitch
        GoSwitchLabel               matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        timefieldSwitch             matlab.ui.control.Switch
        Label                       matlab.ui.control.Label
        TimeFieldEditField          matlab.ui.control.EditField
        TimeFieldEditFieldLabel     matlab.ui.control.Label
        AnalysisPanel               matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        MovingWinGC                 matlab.ui.control.EditField
        MovingWinEditField_2Label   matlab.ui.control.Label
        TincsFTA                    matlab.ui.control.NumericEditField
        TincsEditField_2Label       matlab.ui.control.Label
        TwinsFTA                    matlab.ui.control.NumericEditField
        TwinsEditField_2Label       matlab.ui.control.Label
        TimeFTACheckBox             matlab.ui.control.CheckBox
        TincSTA                     matlab.ui.control.NumericEditField
        TincsEditFieldLabel         matlab.ui.control.Label
        TwinsSTA                    matlab.ui.control.NumericEditField
        TwinsEditFieldLabel         matlab.ui.control.Label
        TimeSTACheckBox             matlab.ui.control.CheckBox
        MovingWinCohe               matlab.ui.control.EditField
        MovingWinLabel              matlab.ui.control.Label
        TimeCoherenceCheckBox       matlab.ui.control.CheckBox
        TimeSpectrumCheckBox        matlab.ui.control.CheckBox
        GrangerCausalityCheckBox    matlab.ui.control.CheckBox
        FsFTA                       matlab.ui.control.NumericEditField
        FsHzEditField_4Label        matlab.ui.control.Label
        FilterBandFTA               matlab.ui.control.EditField
        FilterBandHzEditFieldLabel  matlab.ui.control.Label
        TimeSTA                     matlab.ui.control.EditField
        TimesEditField_2Label       matlab.ui.control.Label
        FTACheckBox                 matlab.ui.control.CheckBox
        fsSTA                       matlab.ui.control.NumericEditField
        FsHzEditField_2Label        matlab.ui.control.Label
        OutWinsSTA                  matlab.ui.control.EditField
        OutWinsEditFieldLabel       matlab.ui.control.Label
        TimesFTA                    matlab.ui.control.EditField
        TimesEditFieldLabel         matlab.ui.control.Label
        STACheckBox                 matlab.ui.control.CheckBox
        CoherenceCheckBox           matlab.ui.control.CheckBox
        SpectrumCheckBox            matlab.ui.control.CheckBox
        MultiTaperParametersPanel   matlab.ui.container.Panel
        mtTrialave                  matlab.ui.control.NumericEditField
        trialaveEditFieldLabel      matlab.ui.control.Label
        mtPad                       matlab.ui.control.NumericEditField
        padEditFieldLabel           matlab.ui.control.Label
        mtFpass                     matlab.ui.control.EditField
        fpassEditFieldLabel         matlab.ui.control.Label
        mtTW                        matlab.ui.control.NumericEditField
        TWEditFieldLabel            matlab.ui.control.Label
        mtFs                        matlab.ui.control.NumericEditField
        FsHzEditFieldLabel          matlab.ui.control.Label
        WorkListPanel               matlab.ui.container.Panel
        LFPListBox_work             matlab.ui.control.ListBox
        LFPListBox_2Label           matlab.ui.control.Label
        SPKCListBox_work            matlab.ui.control.ListBox
        SPKCListBoxLabel            matlab.ui.control.Label
        OriginalListPanel           matlab.ui.container.Panel
        LFPListBox_ori              matlab.ui.control.ListBox
        LFPListBoxLabel             matlab.ui.control.Label
        SPKCListBox_ori             matlab.ui.control.ListBox
        SPKCListBox_2Label          matlab.ui.control.Label
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end

    
    properties (Access = private)
        OriData % Description
        OutDir

    end
    
    methods (Access = private)
        
        function results = analysis(app,lfpname,spkname,savePath)
            disp([lfpname,'+',spkname ' is Processing.......']);
            eval(['lfp_ori=app.OriData.',lfpname,';']);
            eval(['spk=app.OriData.',spkname,';']);
            timefield=text2num(app,app.TimeFieldEditField.Value);
            if strcmp(app.timefieldSwitch.Value,'On')
                for ss=1:numel(spk)
                    spk(ss).times=spk(ss).times-timefield(1);   
                end
            end
            lfp=lfp_norm(lfp_ori);
            params.Fs = app.mtFs.Value;
            TW=app.mtTW.Value;     %... sampling frequency
            ntapers=2*TW-1;
            fpass=text2num(app,app.mtFpass.Value);
            pad=app.mtPad.Value;
            trialave=app.mtTrialave.Value;
            params.tapers=[TW,ntapers];	%... time-bandwidth product & tapers.
            params.fpass=fpass;       %... fpass
            params.pad = pad;             %... padding.
            params.trialave = trialave;		%... trial average.
            if app.SpectrumCheckBox.Value
                [C,phi,~,Sf,Ss,f]=coherencycpt(lfp,spk,params);
                spec.lfp=lfpname;spec.neuron=spkname;spec.C=C;spec.Phi=phi;spec.Sf=Sf;spec.Ss=Ss;spec.f=f;
                results.spec=spec;
                disp('Spectrum Done!');
            end
            if app.TimeSpectrumCheckBox.Value
                movingwin=text2num(app,app.MovingWinCohe.Value);
                [C,phi,~,Sf,Ss,t,f]=cohgramcpt(lfp,spk,movingwin,params);
                tspectrum.lfp=lfpname;tspectrum.neuron=spkname;tspectrum.C=C;tspectrum.Phi=phi;tspectrum.Sf=Sf;tspectrum.Ss=Ss;tspectrum.f=f;tspectrum.t=t;
                results.tspec=tspectrum;
                disp('Time-Spectrum Done');

                figure;
                imagesc(t+timefield(1),f,Ss');colorbar;
                xlabel('Time(s)');ylabel('Frequency(Hz)');title ([lfpname,' Time-frequency']);set(gca, 'FontSize', 14)
                saveas(gcf, fullfile(savePath, [lfpname,'.png']));
                figure;
                imagesc(t+timefield(1),f,Sf');colorbar;
                xlabel('Time(s)');ylabel('Frequency(Hz)');title ([spkname,' Spike Time-frequency']);set(gca, 'FontSize', 14)
                saveas(gcf, fullfile(savePath,[spkname,'.png']));
                figure;
                imagesc(t+timefield(1),f,C');colorbar;
                xlabel('Time(s)');ylabel('Frequency(Hz)');title ([spkname,'_',lfpname,' Coherence']);set(gca, 'FontSize', 14)
                saveas(gcf, fullfile(savePath, [spkname,'_',lfpname,' Cohe.png']));
                close all

            end
            if app.STACheckBox.Value                
                T=text2num(app,app.TimeSTA.Value);
                D=text2num(app,app.OutWinsSTA.Value);
                smpFs=app.fsSTA.Value;
                smp=T(1):1/smpFs:T(2);
                plt='n';
                Fs=40000;
                d = designfilt('lowpassiir', 'FilterOrder', 8, ...
                           'PassbandFrequency', 30, 'PassbandRipple', 0.2,...
                           'SampleRate', Fs);
                for k = 1:size(lfp,2)
                   LFPf(:,k) = filtfilt(d,lfp(:,k));
                end
                disp('filt done');
                [s,t] = sta(spk,LFPf,smp,plt,0,T,D);
                indepsta.lfp=lfpname;indepsta.neuron=spkname;indepsta.sta=s;indepsta.t=t;
                results.indepSTA=indepsta;
                disp('STA Done');
            end
            if app.TimeSTACheckBox.Value
                Tc=text2num(app,app.TimeSTA.Value);
                D=text2num(app,app.OutWinsSTA.Value);
                smpFs=app.fsSTA.Value;
                smp=T(1):1/smpFs:T(2);
                plt='n';
                Tw=app.TwinsSTA.Value;
                Tinc=app.TincSTA.Value;
                                Fs=40000;
                d = designfilt('lowpassiir', 'FilterOrder', 8, ...
                           'PassbandFrequency', 30, 'PassbandRipple', 0.2,...
                           'SampleRate', Fs);
                for k = 1:size(lfp,2)
                   LFPf(:,k) = filtfilt(d,lfp(:,k));
                end
                disp('filt done');
                [S,tau,tc] = staogram(spk,LFPf,smp,plt,Tc,Tinc,Tw,0,D);
                timesta.lfp=lfpname;timesta.neuron=spkname;timesta.sta=S;timesta.tc=tc;timesta.tau=tau;
                results.tSTA=timesta;
                disp('Time-STA Done');
            end
            if app.FTACheckBox.Value
                fs=app.FsFTA.Value;
                wb=text2num(app,app.FilterBandFTA.Value);
                T=text2num(app,app.TimesFTA.Value);
                t=T(1):1/fs:T(2);
                                d = designfilt('lowpassiir', 'FilterOrder', 8, ...
                           'PassbandFrequency', 30, 'PassbandRipple', 0.2,...
                           'SampleRate', Fs);
                for k = 1:size(lfp,2)
                   LFPf(:,k) = filtfilt(d,lfp(:,k));
                end
                [ftaa,phi] = fta(spk,LFPf,fs,wb,t);
                indepfta.lfp=lfpname;indepfta.neuron=spkname;indepfta.fta=ftaa;indepfta.phi=phi;
                results.indepFTA=indepfta;
                disp('FTA Done');
            end
            if app.TimeFTACheckBox.Value
                fs=app.FsFTA.Value;
                wb=text2num(app,app.FilterBandFTA.Value);
                Tc=text2num(app,app.TimesFTA.Value);
                t=T(1):1/fs:T(2);
                Tw=app.TwinsFTA.Value;
                Tinc=app.TincsFTA.Value;
                                d = designfilt('lowpassiir', 'FilterOrder', 8, ...
                           'PassbandFrequency', 30, 'PassbandRipple', 0.2,...
                           'SampleRate', Fs);
                for k = 1:size(lfp,2)
                   LFPf(:,k) = filtfilt(d,lfp(:,k));
                end
                [F,phi,tc] = ftaogram(spk,LFPf,t,Tc,Tinc,Tw,wb,fs);
                timefta.lfp=lfpname;timefta.neuron=spkname;timefta.fta=F;timefta.phi=phi;timefta.tc=tc;
                results.tFTA=timefta;
                disp('Time-FTA Done');
            end
            if app.GrangerCausalityCheckBox.Value
                movingwin=text2num(app,app.MovingWinGC.Value);
                [~,~,~,~,tt,f,GCs2p,GCp2s]=spk_lfp_GC(lfp,spk,params,movingwin);
                GC.lfp=lfpname;GC.neuron=spkname;GC.t=tt;GC.f=f;GC.s2p=GCs2p;GC.p2s=GCp2s;
                results.GC=GC;
                disp('Time-GC Done');
            end

        end
        
        function numArray = text2num(app,str)
            str = strrep(str, '[', ''); % 去除左方括号
            str = strrep(str, ']', ''); % 去除右方括号
            % 使用 strsplit 函数分割字符串
            strArray = strsplit(str, ',');
            % 将字符串数组转换为数值数组
            numArray = str2double(strArray);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: WorkFileMenu
        function WorkFileMenuSelected(app, event)
            [filename, filepath] = uigetfile({'*.mat', 'Mat Files (*.mat)'}, '选择数据文件');
            % 检查用户是否取消选择
            if isequal(filename, 0)
                uiconfirm(app.UIFigure, 'User canceld the option!', 'Note');
            else
                selectedFile = fullfile(filepath, filename);
                app.OriData=load(selectedFile);
                vars = fieldnames(app.OriData);
                % 将匹配的变量名添加到 ListBox 中显示
                prefix = 'SPK';
                matchingVars = vars(startsWith(vars, prefix));  
                app.SPKCListBox_ori.Items = matchingVars;
                app.SPKCListBox_work.Items = matchingVars;
                prefix = 'LFP';
                matchingVars = vars(startsWith(vars, prefix));  
                app.LFPListBox_ori.Items = matchingVars;
                app.LFPListBox_work.Items = matchingVars;
                uiconfirm(app.UIFigure, 'Load Data Done!', 'Note',"Options",'OK');
            end
        end

        % Double-clicked callback: SPKCListBox_ori
        function SPKCListBox_oriDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            name=app.SPKCListBox_ori.Value;
            allitem=app.SPKCListBox_work.Items;
            app.SPKCListBox_work.Items=[allitem,{name}];
        end

        % Double-clicked callback: LFPListBox_ori
        function LFPListBox_oriDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            name=app.LFPListBox_ori.Value;
            allitem=app.LFPListBox_work.Items;
            app.LFPListBox_work.Items=[allitem,{name}];
        end

        % Double-clicked callback: SPKCListBox_work
        function SPKCListBox_workDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            app.SPKCListBox_work.Items(item)=[];
        end

        % Double-clicked callback: LFPListBox_work
        function LFPListBox_workDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            app.LFPListBox_work.Items(item)=[];
        end

        % Value changed function: GoSwitch
        function GoSwitchValueChanged(app, event)
            value = app.GoSwitch.Value;
            if value=="On"
                for i=1:length(app.SPKCListBox_work.Items)
                    spkcname=app.SPKCListBox_work.Items{i};
                    lfpname=app.LFPListBox_work.Items{i};
                    resultname=[spkcname,'_',lfpname];
                    eval([resultname,'=analysis(app,lfpname,spkcname,app.OutDir);']);
                    eval(['TotalRe.',resultname,'=',resultname,';']);
                end

                save(fullfile(app.OutDir,'AnalyzedRe.mat'),'-struct','TotalRe');
                disp ('Save results done!')
            end
        end

        % Menu selected function: OutDirMenu
        function OutDirMenuSelected(app, event)
            startPath = pwd; % 设置初始文件夹路径
            selectedFolder = uigetdir(startPath, '选择文件夹'); % 打开文件夹对话框
            if selectedFolder == 0
                uiconfirm(app.UIFigure, 'User canceld the option!', 'Note');
            else
                app.OutDirEditField.Value=selectedFolder;
                app.OutDir=selectedFolder;
            end
        end

        % Clicked callback: SPKCListBox_work
        function SPKCListBox_workClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WorkFileMenu = uimenu(app.UIFigure);
            app.WorkFileMenu.MenuSelectedFcn = createCallbackFcn(app, @WorkFileMenuSelected, true);
            app.WorkFileMenu.Text = 'WorkFile';
            app.OutDirMenu = uimenu(app.UIFigure);
            app.OutDirMenu.MenuSelectedFcn = createCallbackFcn(app, @OutDirMenuSelected, true);
            app.OutDirMenu.Text = 'OutDir';
            app.OriginalListPanel = uipanel(app.UIFigure);
            app.OriginalListPanel.Title = 'Original List ';
            app.SPKCListBox_2Label = uilabel(app.OriginalListPanel);
            app.SPKCListBox_2Label.Text = 'SPKC';
            app.SPKCListBox_ori = uilistbox(app.OriginalListPanel);
            app.SPKCListBox_ori.Items = {};
            app.SPKCListBox_ori.DoubleClickedFcn = createCallbackFcn(app, @SPKCListBox_oriDoubleClicked, true);
            app.SPKCListBox_ori.Value = {};
            app.LFPListBoxLabel = uilabel(app.OriginalListPanel);
            app.LFPListBoxLabel.Text = 'LFP';
            app.LFPListBox_ori = uilistbox(app.OriginalListPanel);
            app.LFPListBox_ori.Items = {};
            app.LFPListBox_ori.DoubleClickedFcn = createCallbackFcn(app, @LFPListBox_oriDoubleClicked, true);
            app.LFPListBox_ori.Value = {};
            app.WorkListPanel = uipanel(app.UIFigure);
            app.WorkListPanel.Title = 'Work List';
            app.SPKCListBoxLabel = uilabel(app.WorkListPanel);
            app.SPKCListBoxLabel.Text = 'SPKC';
            app.SPKCListBox_work = uilistbox(app.WorkListPanel);
            app.SPKCListBox_work.Items = {};
            app.SPKCListBox_work.ClickedFcn = createCallbackFcn(app, @SPKCListBox_workClicked, true);
            app.SPKCListBox_work.DoubleClickedFcn = createCallbackFcn(app, @SPKCListBox_workDoubleClicked, true);
            app.SPKCListBox_work.Value = {};
            app.LFPListBox_2Label = uilabel(app.WorkListPanel);
            app.LFPListBox_2Label.Text = 'LFP';
            app.LFPListBox_work = uilistbox(app.WorkListPanel);
            app.LFPListBox_work.Items = {};
            app.LFPListBox_work.DoubleClickedFcn = createCallbackFcn(app, @LFPListBox_workDoubleClicked, true);
            app.LFPListBox_work.Value = {};
            app.MultiTaperParametersPanel = uipanel(app.UIFigure);
            app.MultiTaperParametersPanel.Title = 'Multi-Taper Parameters';
            app.FsHzEditFieldLabel = uilabel(app.MultiTaperParametersPanel);
            app.FsHzEditFieldLabel.Text = 'Fs (Hz)';
            app.mtFs = uieditfield(app.MultiTaperParametersPanel, 'numeric');
            app.mtFs.Value = 1000;
            app.TWEditFieldLabel = uilabel(app.MultiTaperParametersPanel);
            app.TWEditFieldLabel.Text = 'TW';
            app.mtTW = uieditfield(app.MultiTaperParametersPanel, 'numeric');
            app.mtTW.Value = 3;
            app.fpassEditFieldLabel = uilabel(app.MultiTaperParametersPanel);
            app.fpassEditFieldLabel.Text = 'fpass';
            app.mtFpass = uieditfield(app.MultiTaperParametersPanel, 'text');
            app.mtFpass.Value = '[0,200]';
            app.padEditFieldLabel = uilabel(app.MultiTaperParametersPanel);
            app.padEditFieldLabel.Text = 'pad';
            app.mtPad = uieditfield(app.MultiTaperParametersPanel, 'numeric');
            app.mtPad.Value = 2;
            app.trialaveEditFieldLabel = uilabel(app.MultiTaperParametersPanel);
            app.trialaveEditFieldLabel.Text = 'trialave';
            app.mtTrialave = uieditfield(app.MultiTaperParametersPanel, 'numeric');
            app.mtTrialave.Value = 1;
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis Panel';
            app.GridLayout = uigridlayout(app.AnalysisPanel);
            app.SpectrumCheckBox = uicheckbox(app.GridLayout);
            app.SpectrumCheckBox.Text = 'Spectrum';
            app.SpectrumCheckBox.Value = true;
            app.CoherenceCheckBox = uicheckbox(app.GridLayout);
            app.CoherenceCheckBox.Text = 'Coherence';
            app.CoherenceCheckBox.Value = true;
            app.STACheckBox = uicheckbox(app.GridLayout);
            app.STACheckBox.Text = 'STA';
            app.STACheckBox.Value = true;
            app.TimesEditFieldLabel = uilabel(app.GridLayout);
            app.TimesEditFieldLabel.Text = 'Time (s)';
            app.TimesFTA = uieditfield(app.GridLayout, 'text');
            app.TimesFTA.Value = '[0,2]';
            app.OutWinsEditFieldLabel = uilabel(app.GridLayout);
            app.OutWinsEditFieldLabel.Text = 'OutWin (s)';
            app.OutWinsSTA = uieditfield(app.GridLayout, 'text');
            app.OutWinsSTA.Value = '[-0.1,0.1]';
            app.FsHzEditField_2Label = uilabel(app.GridLayout);
            app.FsHzEditField_2Label.Text = 'Fs (Hz)';
            app.fsSTA = uieditfield(app.GridLayout, 'numeric');
            app.fsSTA.Value = 40000;
            app.FTACheckBox = uicheckbox(app.GridLayout);
            app.FTACheckBox.Text = 'FTA';
            app.FTACheckBox.Value = true;
            app.TimesEditField_2Label = uilabel(app.GridLayout);
            app.TimesEditField_2Label.Text = 'Time (s)';
            app.TimeSTA = uieditfield(app.GridLayout, 'text');
            app.TimeSTA.Value = '[0,2]';
            app.FilterBandHzEditFieldLabel = uilabel(app.GridLayout);
            app.FilterBandHzEditFieldLabel.Text = 'Filter Band(Hz)';
            app.FilterBandFTA = uieditfield(app.GridLayout, 'text');
            app.FilterBandFTA.Value = '[0,120]';
            app.FsHzEditField_4Label = uilabel(app.GridLayout);
            app.FsHzEditField_4Label.Text = 'Fs (Hz)';
            app.FsFTA = uieditfield(app.GridLayout, 'numeric');
            app.FsFTA.Value = 40000;
            app.GrangerCausalityCheckBox = uicheckbox(app.GridLayout);
            app.GrangerCausalityCheckBox.Text = 'Granger Causality';
            app.GrangerCausalityCheckBox.Value = true;
            app.TimeSpectrumCheckBox = uicheckbox(app.GridLayout);
            app.TimeSpectrumCheckBox.Text = 'Time-Spectrum';
            app.TimeSpectrumCheckBox.Value = true;
            app.TimeCoherenceCheckBox = uicheckbox(app.GridLayout);
            app.TimeCoherenceCheckBox.Text = 'Time-Coherence';
            app.TimeCoherenceCheckBox.Value = true;
            app.MovingWinLabel = uilabel(app.GridLayout);
            app.MovingWinLabel.Text = 'Moving Win';
            app.MovingWinCohe = uieditfield(app.GridLayout, 'text');
            app.MovingWinCohe.Value = '[0.15,0.01]';
            app.TimeSTACheckBox = uicheckbox(app.GridLayout);
            app.TimeSTACheckBox.Text = 'Time-STA';
            app.TimeSTACheckBox.Value = true;
            app.TwinsEditFieldLabel = uilabel(app.GridLayout);
            app.TwinsEditFieldLabel.Text = 'Twin (s)';
            app.TwinsSTA = uieditfield(app.GridLayout, 'numeric');
            app.TwinsSTA.Value = 0.5;
            app.TincsEditFieldLabel = uilabel(app.GridLayout);
            app.TincsEditFieldLabel.Text = 'Tinc (s)';
            app.TincSTA = uieditfield(app.GridLayout, 'numeric');
            app.TincSTA.Value = 0.1;
            app.TimeFTACheckBox = uicheckbox(app.GridLayout);
            app.TimeFTACheckBox.Text = 'Time-FTA';
            app.TimeFTACheckBox.Value = true;
            app.TwinsEditField_2Label = uilabel(app.GridLayout);
            app.TwinsEditField_2Label.Text = 'Twin (s)';
            app.TwinsFTA = uieditfield(app.GridLayout, 'numeric');
            app.TwinsFTA.Value = 0.5;
            app.TincsEditField_2Label = uilabel(app.GridLayout);
            app.TincsEditField_2Label.Text = 'Tinc (s)';
            app.TincsFTA = uieditfield(app.GridLayout, 'numeric');
            app.TincsFTA.Value = 0.1;
            app.MovingWinEditField_2Label = uilabel(app.GridLayout);
            app.MovingWinEditField_2Label.Text = 'Moving Win';
            app.MovingWinGC = uieditfield(app.GridLayout, 'text');
            app.MovingWinGC.Value = '[0.15,0.01]';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TimeFieldEditFieldLabel = uilabel(app.Panel);
            app.TimeFieldEditFieldLabel.Text = 'Time Field';
            app.TimeFieldEditField = uieditfield(app.Panel, 'text');
            app.TimeFieldEditField.Value = '[-1,1.5]';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '是否应用计算';
            app.timefieldSwitch = uiswitch(app.Panel, 'slider');
            app.GoSwitchLabel = uilabel(app.UIFigure);
            app.GoSwitchLabel.Text = 'Go!';
            app.GoSwitch = uiswitch(app.UIFigure, 'toggle');
            app.GoSwitch.ValueChangedFcn = createCallbackFcn(app, @GoSwitchValueChanged, true);
            app.OutDirEditFieldLabel = uilabel(app.UIFigure);
            app.OutDirEditFieldLabel.Text = 'OutDir';
            app.OutDirEditField = uieditfield(app.UIFigure, 'text');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = neuroAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end