classdef neuroCombine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        workdir1Menu                 matlab.ui.container.Menu
        workdir2Menu                 matlab.ui.container.Menu
        ImportMenu                   matlab.ui.container.Menu
        nowButton                    matlab.ui.control.Button
        pcatree                      matlab.ui.container.CheckBoxTree
        DatanameEditField            matlab.ui.control.EditField
        DatanameEditFieldLabel       matlab.ui.control.Label
        StartButton                  matlab.ui.control.StateButton
        CombineFromEditField         matlab.ui.control.EditField
        CombineFromEditFieldLabel    matlab.ui.control.Label
        combineOut                   matlab.ui.control.Button
        combineDir                   matlab.ui.control.Button
        CombineOutputEditField       matlab.ui.control.EditField
        CombineOutputEditFieldLabel  matlab.ui.control.Label
        SortedGroupsLabel            matlab.ui.control.Label
        UnsortedLabel                matlab.ui.control.Label
        Unsorted                     matlab.ui.container.Tree
        groupname2                   matlab.ui.control.EditField
        GroupEditField_2Label        matlab.ui.control.Label
        AddButton_2                  matlab.ui.control.Button
        AddButton                    matlab.ui.control.Button
        groupname1                   matlab.ui.control.EditField
        GroupEditFieldLabel          matlab.ui.control.Label
        Waveform1DropDown            matlab.ui.control.DropDown
        Waveform1DropDownLabel       matlab.ui.control.Label
        Groups                       matlab.ui.container.Tree
        Waveform2DropDown            matlab.ui.control.DropDown
        Waveform2DropDownLabel       matlab.ui.control.Label
        pcaplot                      matlab.ui.control.UIAxes
        ISI2                         matlab.ui.control.UIAxes
        autocorr                     matlab.ui.control.UIAxes
        ISI1                         matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        workpath1 % graph path
        workpath2
        plottime
        wavedata1
        wavedata2
        isidata1
        isidata2
        Unsortlabel
        gname1
        gname2
        wfname1
        wfname2
        combineWorkdir
        combineOutdir
    end
    
    methods (Access = private)
        
        function waveform=plotgraph(app,dirpath,csvname,color,uiaxe,isiaxe)
            % 读取CSV文件中的数据
            filepath=[dirpath,'/',csvname,'.csv'];
            data = csvread(filepath, 1, 0);  % 从第二行开始读取数据（假设第一行是列名）
            % 提取时间、波形数值和波形标准差数值
            time = data(:, 1);
            waveform = data(:, 2);
            std_dev = data(:, 3);
            app.plottime=time;
            
            % 绘制波形图
            plot(uiaxe,time, waveform, color, 'LineWidth', 1.5);  % 绘制波形
            errorbar(uiaxe,time, waveform, std_dev, color, 'LineWidth', 1.5);  % 绘制波形标准差
            xlabel(uiaxe,'Time');
            ylabel(uiaxe,'Amplitude');
            title(uiaxe,'Waveform with Standard Deviation');
            grid(uiaxe, 'on');

            % 读取CSV文件中的ISI
            filepath=[dirpath,'/ISI_',csvname,'.csv'];
            data = csvread(filepath, 1, 0);  % 从第二行开始读取数据（假设第一行是列名）
            % 提取时间、波形数值和波形标准差数值
            bin = data(:, 1);
            isi = data(:, 2);

            % 绘制ISI图
            bar(isiaxe,bin,isi,color)  % 绘制ISI
            xlabel(isiaxe,'Time(s)');
            ylabel(isiaxe,'Count');
            title(isiaxe,'ISI hist');
            grid(isiaxe, 'on');
            
        end
        
    
        function file_names=updateitem(app,tmppath)
            % 指定文件夹路径
            folder_path = tmppath;
            % 获取文件夹中的所有文件和文件夹信息
            files = dir(folder_path);
            % 创建一个空的文件名列表
            file_names = {};
            % 遍历文件夹中的每个文件和文件夹
            for i = 1:numel(files)
                % 排除当前目录（'.'）和上级目录（'..'）以及ISI数据
                if ~files(i).isdir && ~startsWith(files(i).name, '.') && ~startsWith(files(i).name, 'ISI') && ~strcmp(files(i).name(end-2:end), 'mat')
                    % 使用 fileparts 函数获取文件名（不带扩展名）
                    [~, name, ~] = fileparts(files(i).name);
                    % 将文件名添加到列表中
                    %name=extractAfter(name,'_');
                    file_names{end+1} = name;
                end
            end
        
        end


        function updatemix(app)
             % 绘制波形图
            
            plot(app.UIAxes_2,app.plottime, app.wavedata1, 'b', 'LineWidth', 1.5);  % 绘制波形
            ylim(app.UIAxes_2,[-0.15 0.15]);
            hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2,app.plottime, app.wavedata2, 'r', 'LineWidth', 1.5);  % 绘制波形
            xlabel(app.UIAxes_2,'Time');
            ylabel(app.UIAxes_2,'Amplitude');
            title(app.UIAxes_2,'Mixed');
            grid(app.UIAxes_2, 'on');
            hold(app.UIAxes_2, 'off');
            ylim(app.UIAxes_2,[min(min(app.wavedata1,app.wavedata2))-0.05,max(max(app.wavedata1,app.wavedata2))+0.05]);

            [corr,lag]=xcorr(app.wavedata1);
            plot(app.autocorr,lag,corr, 'b', 'LineWidth', 1.5);  % 绘制波形
            %ylim(app.UIAxes_2,[-0.15 0.15]);
            hold(app.autocorr, 'on');
            [corr,lag]=xcorr(app.wavedata2);
            plot(app.autocorr,lag,corr, 'r', 'LineWidth', 1.5);  % 绘制波形
            xlabel(app.autocorr,'Lag');
            ylabel(app.autocorr,'Amplitude');
            title(app.autocorr,'Autocorrelation');
            grid(app.autocorr, 'on');
            hold(app.autocorr, 'off');
        end
        
        function updateUnsort(app,listUnsort,addOrdel)
            for i=1:length(listUnsort)
                tmpname=listUnsort{i};
                trialname=['Channel',tmpname(end-2:end-1)];
                parentNode=app.Unsorted.Children;
                if isempty(parentNode)
                    parentNode = uitreenode(app.Unsorted, "Text", trialname);
                    uitreenode(parentNode, "Text", tmpname); 
                else
                    parentNodeName={parentNode.Text};
                    kk=strcmp(parentNodeName,trialname);
                    if isempty(find(kk== 1))
                        parentNode = uitreenode(app.Unsorted, "Text", trialname);
                        uitreenode(parentNode, "Text", tmpname);
                    else
                        parentNode=parentNode(kk);
                        if addOrdel==1
                            uitreenode(parentNode, "Text", tmpname);
                        else
                            parentNode=parentNode.Children;
                            parentNodeName={parentNode.Text};
                            kk=strcmp(parentNodeName,tmpname);
                            delNode=parentNode(kk);
                            parent=delNode.Parent;
                            delNode.delete;
                            chil=parent.Children;
                            if isempty(chil)
                                parent.delete;
                            end
                        end
                    end
                end
            end
            expand(app.Unsorted);
        end
        
        function updatepcatree(app,listUnsort,addOrdel)
            for i=1:length(listUnsort)
                tmpname=listUnsort{i};
                trialname=['Channel',tmpname(end-2:end-1)];
                parentNode=app.pcatree.Children;
                if isempty(parentNode)
                    parentNode = uitreenode(app.pcatree, "Text", trialname);
                    uitreenode(parentNode, "Text", tmpname); 
                else
                    parentNodeName={parentNode.Text};
                    kk=strcmp(parentNodeName,trialname);
                    if isempty(find(kk== 1))
                        parentNode = uitreenode(app.pcatree, "Text", trialname);
                        uitreenode(parentNode, "Text", tmpname);
                    else
                        parentNode=parentNode(kk);
                        if addOrdel==1
                            uitreenode(parentNode, "Text", tmpname);
                        else
                            parentNode=parentNode.Children;
                            parentNodeName={parentNode.Text};
                            kk=strcmp(parentNodeName,tmpname);
                            delNode=parentNode(kk);
                            parent=delNode.Parent;
                            delNode.delete;
                            chil=parent.Children;
                            if isempty(chil)
                                parent.delete;
                            end
                        end
                    end
                end
            end
            expand(app.pcatree);
        end

        function CombineData(app)
            RootNode=app.Groups.Children;
            resultdata=struct();
            disp('Loading Original Data...');
            %去读所有data文件夹里的data
            % 获取文件夹列表
            folders = dir(app.combineWorkdir);
            % 遍历文件夹
            for i = 1:length(folders)
                % 检查文件夹名是否以"TRIAL"和数字结尾
                if ~folders(i).isdir
                    continue;
                end
                
                folderName = folders(i).name;
                trialnum=extractAfter(folderName, 'TRIAL');
                structname=['TRIAL',trialnum];
                if ~isempty(trialnum)
                    % 读取数据文件
                    dataname=app.DatanameEditField.Value;
                    dataFilePath = fullfile(app.combineWorkdir, folderName, [dataname,'.mat']);
                    eval([structname,'= load(dataFilePath);']);     
                    % dataFilePath = fullfile(app.combineWorkdir, folderName, ['StimAngle','.mat']);
                    % ttmpangle=load(dataFilePath);
                    % tmpangle=reshape((ttmpangle.angleRec)',[],1);
                    % eval([structname,'.angle= tmpangle;']); 
                    % dataFilePath = fullfile(app.combineWorkdir, folderName, ['StimSpeed','.mat']);
                    % ttmpspeed=load(dataFilePath);
                    % tmpspeed=reshape((ttmpspeed.speedRec)',[],1);
                    % eval([structname,'.speed= tmpspeed;']); 
                end
            end
            ch_tr=cell(1,16);
            disp('Combine Data...');
            for i=1:length(RootNode)
                ParentNode=RootNode(i);
                neuroname=ParentNode.Text;
                if length(neuroname)==2
                    neuroname=['0',neuroname];
                end
                neurondir=fullfile(app.combineOutdir,neuroname);
                %mkdir(neurondir);
                ChildrenNode=ParentNode.Children;
                eval(['SPKC',neuroname,'=[];']);
                % eval(['LFP',neuroname,'=[];']);
                eval(['StimAngle',neuroname,'=[];']);
                eval(['StimSpeed',neuroname,'=[];']);
                eval(['Waveform',neuroname,'=[];']);
                eval(['Order',neuroname,'=[];']);
                tmpwave=[];
                tmptime=[];
                tmpdata=[];
                ch=str2double(neuroname(end-2:end-1));
                for j=1:length(ChildrenNode)
                    neuronode=ChildrenNode(j);
                    dataname=neuronode.Text;
                    filepath=[app.workpath1,'/',dataname,'.csv'];
                    wavedata = csvread(filepath, 1, 0);  % 从第二行开始读取数据（假设第一行是列名）
                    % 提取时间、波形数值和波形标准差数值
                    tmptime = wavedata(:, 1);
                    waveform = wavedata(:, 2);
                    tmpdata=[tmpdata,{dataname}];
                    tmpwave=[tmpwave,waveform];
                    [trialname,spkname]=strtok(dataname,'_');
                    
                    trialn=str2double(trialname(end-1:end));
                    if ~ismember(trialn, ch_tr{ch})
                    ch_tr(ch)={[ch_tr{ch},trialn]};
                    end

                    firstRow = repelem(trialn, 120);
                    secondRow = 1:120;
                    rArray=[firstRow;secondRow];
                    spkname=spkname(2:end);
                    % lfpname=['LFP',extractAfter(spkname,'SPKC')];
                    % lfpname=lfpname(1:5);
                    angname=['StimAngle',extractAfter(spkname,'SPKC')];
                    angname=angname(1:end-1);
                    spename=['StimSpeed',extractAfter(spkname,'SPKC')];
                    spename=spename(1:end-1);
                    % orname=['Order',extractAfter(spkname,'SPKC')];
                    eval(['SPKC',neuroname,'=[','SPKC',neuroname,',',trialname,'.',spkname,'];']);
                    % eval(['LFP',neuroname,'=[','LFP',neuroname,',',trialname,'.',lfpname,'];']);
                    % eval(['StimAngle',neuroname,'=[','StimAngle',neuroname,';',trialname,'.angle];']);
                    % eval(['StimSpeed',neuroname,'=[','StimSpeed',neuroname,';',trialname,'.speed];']);
                    eval(['StimAngle',neuroname,'=[','StimAngle',neuroname,';',trialname,'.',angname,'];']);
                    eval(['StimSpeed',neuroname,'=[','StimSpeed',neuroname,';',trialname,'.',spename,'];']);
                    eval(['Order',neuroname,'=[','Order',neuroname,',rArray];']);
                end
                eval(['Waveform',neuroname,'=mean(tmpwave,2);'])
                eval([ 'CombineDetail.neuron',neuroname '=tmpdata;']);
                var1=['SPKC',neuroname];
                eval(['resultdata.',var1,'=',var1,';']);
                % var2=['LFP',neuroname];
                % eval(['resultdata.',var2,'=',var2,';']);
                var3=['StimAngle',neuroname];
                eval(['resultdata.',var3,'=',var3,';']);
                var4=['StimSpeed',neuroname];
                eval(['resultdata.',var4,'=',var4,';']);
                var5=['Waveform',neuroname];
                eval(['resultdata.',var5,'=',var5,';']);
                var6=['Wavetime',neuroname];
                eval(['resultdata.',var6,'=tmptime;']);
                var7=['Order',neuroname];
                eval(['resultdata.',var7,'=',var7,';']);
                disp(['Combine Neuron ',neuroname,' done!']);
            end
            
            for i=1:numel(ch_tr)
                if ~isempty(ch_tr{i})
                    tn=ch_tr{i};
                    tn=sort(tn);


                    lfpname=['LFP',sprintf('%02d', i)];
                    ordname=['Order',sprintf('%02d', i)];
                    tmplfp=[];
                    tmpor=[];
                    for jj=1:length(tn)
                        firstRow = repelem(tn(jj), 120);
                        secondRow = 1:120;
                        rArray=[firstRow;secondRow];
                        trialname=['TRIAL',sprintf('%02d', tn(jj))];
                        eval(['tmplfp=[tmplfp,',trialname,'.',lfpname,'];']);
                        tmpor=[tmpor,rArray];
                    end
                    
                    eval(['resultdata.',lfpname,'=tmplfp;']);
                    eval(['resultdata.',ordname,'=tmpor;']);
                end
                
            end
            save(fullfile(app.combineOutdir,'Combined.mat'), '-struct', 'resultdata');
            save(fullfile(app.combineOutdir,'CombineDetail.mat'), '-struct', 'CombineDetail');
            disp('Save Data Done!')
        end
        
        function updateImport(app,importData)
                child=app.Groups.Children;
                child.delete;
                child=app.Unsorted.Children;
                child.delete;
                neuronames=fieldnames(importData);
                for i=1:length(neuronames)
                    tmpname=neuronames{i};
                    
                    parentNode = uitreenode(app.Groups, "Text", extractAfter(tmpname,'neuron'));
                    eval(['children=importData.',tmpname,';']);
                    for j=1:length(children)
                        spkNode = uitreenode(parentNode, "Text", children{j});
                    end
                end
            expand(app.Groups);
        end
        
        function results = plotpca(app,wavelist)
            numlist=numel(wavelist);
            precolor=parula(numlist);
            precolor(1,:)=[0 0 1];
            precolor(2,:)=[1 0 0];
            pcadata=[];
            labels=[];
            group_labels=[];
            for i=1:numlist
                loadname=[wavelist{i},'.mat'];
                tmpdata=load(fullfile(app.workpath1,loadname));
                tmpdata=transpose(tmpdata.wfs);
                pcadata=[pcadata,tmpdata];
                group_labels = [group_labels; repmat({wavelist{i}}, size(tmpdata, 2), 1)];
                labels = [labels; repmat(precolor(i,:),size(tmpdata, 2), 1)];
            end
            pcadata=pcadata';
            [coeff, score, ~, ~, explained] = pca(pcadata); % 对合并后的数据进行PCA分析
            h=scatter3(app.pcaplot,score(:,1),score(:,2),score(:,3),50,labels,'.'); % 根据标签绘制散点图
            xlabel(app.pcaplot,'Principal Component 1'); % x轴标签
            ylabel(app.pcaplot,'Principal Component 2'); % y轴标签
            title(app.pcaplot,'PCA of Neural Spike Signals'); % 图标题
            grid(app.pcaplot,'on');
            set(app.pcaplot,'Color',[0.8 0.8 0.8])
            %legend(h, group_labels, 'Location', 'best'); 
            for k = 1:numlist
                text(app.pcaplot,score((k-1)*48+1,1), score((k-1)*48+1,2), score((k-1)*48+1,3),wavelist{k}, 'Color',precolor(k,:), 'FontSize', 10); % 在散点图上标注数据来源
            end
        
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: workdir1Menu
        function workdir1MenuSelected(app, event)
             autoSelect =false;  % 如果为 true，自动选择 waveform 文件夹；为 false，手动选择

            if~autoSelect
                startPath = pwd; % 设置初始文件夹路径
                selectedFolder = uigetdir(startPath, '选择文件夹'); % 打开文件夹对话框
                if selectedFolder == 0
                    uiconfirm(app.UIFigure, 'User canceld the option!', 'Note');
                else
                    disp(['用户选择的文件夹1是：', selectedFolder]);
                    app.workpath1=selectedFolder;
                    app.CombineFromEditField.Value=fileparts(selectedFolder);
                    app.combineWorkdir=app.CombineFromEditField.Value;
                    tmpoutput=fullfile(fileparts(selectedFolder),'combined');
                    app.combineOutdir=tmpoutput;
                    mkdir(tmpoutput);
                    app.CombineOutputEditField.Value=tmpoutput;
                    item1=updateitem(app,app.workpath1);
                    % 在这里可以添加处理选择文件夹后的操作
                    app.Waveform1DropDown.Items=item1;
                    if isempty(app.workpath2)
                        updateUnsort(app,item1,1);
                        updatepcatree(app,item1,1);
                    elseif app.workpath1~=app.workpath2
                        updateUnsort(app,item1,1);
                        updatepcatree(app,item1,1);
                    end
                    app.wfname1=app.Waveform1DropDown.Value;
                    app.wavedata1=plotgraph(app,app.workpath1,app.wfname1,'b',app.UIAxes,app.ISI1);
                    if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                        updatemix(app);
                    end
                end

            else
                    selectedFolder=fullfile(pwd,'waveform/');
                    disp(['用户选择的文件夹1是：', selectedFolder]);
                    app.workpath1=selectedFolder;
                    app.CombineFromEditField.Value=fileparts(selectedFolder);
                    app.combineWorkdir=app.CombineFromEditField.Value;
                    tmpoutput=fullfile(fileparts(selectedFolder),'combined');
                    app.combineOutdir=tmpoutput;
                    mkdir(tmpoutput);
                    app.CombineOutputEditField.Value=tmpoutput;
                    item1=updateitem(app,app.workpath1);
                    % 在这里可以添加处理选择文件夹后的操作
                    app.Waveform1DropDown.Items=item1;
                    if isempty(app.workpath2)
                        updateUnsort(app,item1,1);
                        updatepcatree(app,item1,1);
                    elseif app.workpath1~=app.workpath2
                        updateUnsort(app,item1,1);
                        updatepcatree(app,item1,1);
                    end
                    app.wfname1=app.Waveform1DropDown.Value;
                    app.wavedata1=plotgraph(app,app.workpath1,app.wfname1,'b',app.UIAxes,app.ISI1);
                    if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                        updatemix(app);
                    end
            end
            app.gname2=app.groupname2.Value;
            app.gname1=app.groupname1.Value;
        end

        % Menu selected function: workdir2Menu
        function workdir2MenuSelected(app, event)
            startPath = pwd; % 设置初始文件夹路径
            selectedFolder = uigetdir(startPath, '选择文件夹'); % 打开文件夹对话框
            if selectedFolder == 0
                uiconfirm(app.UIFigure, 'User canceld the option!', 'Note');
            else
                disp(['用户选择的文件夹2是：', selectedFolder]);
                app.workpath2=selectedFolder;
                item2=updateitem(app,app.workpath2);
                % 在这里可以添加处理选择文件夹后的操作
                app.Waveform2DropDown.Items=item2;
                if isempty(app.workpath1)
                    updateUnsort(app,item2,1);
                    updatepcatree(app,item2,1);
                elseif app.workpath1~=app.workpath2
                    updateUnsort(app,item2,1);
                    updatepcatree(app,item2,1);
                end
                app.wfname2=app.Waveform2DropDown.Value;
                app.wavedata2=plotgraph(app,app.workpath2,app.wfname2,'r',app.UIAxes_3,app.ISI2);
                if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                    updatemix(app);
                end
            end            
        end

        % Value changed function: Waveform1DropDown
        function Waveform1DropDownValueChanged(app, event)
            value = app.Waveform1DropDown.Value;
            app.wfname1=value;
            app.wavedata1=plotgraph(app,app.workpath1,value,'b',app.UIAxes,app.ISI1);
            if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                updatemix(app);
            end
        end

        % Value changed function: Waveform2DropDown
        function Waveform2DropDownValueChanged(app, event)
            value = app.Waveform2DropDown.Value;
            app.wfname2=value;
            app.wavedata2=plotgraph(app,app.workpath2,value,'r',app.UIAxes_3,app.ISI2);
            if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                updatemix(app);
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            
            parentNode=app.Groups.Children;
            if isempty(parentNode)
                parentNode = uitreenode(app.Groups, "Text", app.gname1);
                uitreenode(parentNode, "Text", app.wfname1);                    
            else
                parentNodeName={parentNode.Text};
                kk=strcmp(parentNodeName,app.gname1);
                if isempty(find(kk== 1))
                    parentNode = uitreenode(app.Groups, "Text", app.gname1);
                    uitreenode(parentNode, "Text", app.wfname1);
                else
                    parentNode=parentNode(kk);
                    uitreenode(parentNode, "Text", app.wfname1);
                end
            end
            expand(app.Groups,'all');
            updateUnsort(app,{app.wfname1},0);
        end

        % Value changed function: groupname1
        function groupname1ValueChanged(app, event)
            value = app.groupname1.Value;
            app.gname1=value;
        end

        % Value changed function: groupname2
        function groupname2ValueChanged(app, event)
            value = app.groupname2.Value;
            app.gname2=value;
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            parentNode=app.Groups.Children;
            if isempty(parentNode)
                parentNode = uitreenode(app.Groups, "Text", app.gname2);
                uitreenode(parentNode, "Text", app.wfname2);                    
            else
                parentNodeName={parentNode.Text};
                kk=strcmp(parentNodeName,app.gname2);
                if isempty(find(kk== 1))
                    parentNode = uitreenode(app.Groups, "Text", app.gname2);
                    uitreenode(parentNode, "Text", app.wfname2);
                else
                    parentNode=parentNode(kk);
                    uitreenode(parentNode, "Text", app.wfname2);
                end
            end
            updateUnsort(app,{app.wfname2},0);
            expand(app.Groups,'all');
        end

        % Button pushed function: combineDir
        function combineDirButtonPushed(app, event)
            startPath = pwd; % 设置初始文件夹路径
            selectedFolder = uigetdir(startPath, '选择文件夹'); % 打开文件夹对话框
            if selectedFolder == 0
                uiconfirm(app.UIFigure, 'User canceld the option!', 'Note');
            else
                app.combineWorkdir=selectedFolder;
                app.CombineFromEditField.Value=app.combineWorkdir;
            end
        end

        % Button pushed function: combineOut
        function combineOutButtonPushed(app, event)
            startPath = pwd; % 设置初始文件夹路径
            selectedFolder = uigetdir(startPath, '选择文件夹'); % 打开文件夹对话框
            if selectedFolder == 0
                uiconfirm(app.UIFigure, 'User canceld the option!', 'Note');
            else
                app.combineOutdir=selectedFolder;
                app.CombineOutputEditField.Value=app.combineOutdir;
            end
            
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value==1
                app.StartButton.BackgroundColor="red";
                app.StartButton.Text="Processing";
                %app.StartButton.Enable="off";
                CombineData(app);
                uiconfirm(app.UIFigure, 'Combine finished!', 'Note');
                app.StartButton.Value=0;
            else
                app.StartButton.Enable="on";
                app.StartButton.BackgroundColor="green";
                app.StartButton.Text="Start"
            end
        end

        % Double-clicked callback: Groups
        function GroupsDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            updateUnsort(app,{node.Text},1);
            parent=node.Parent;
            node.delete;
            chil=parent.Children;
            if isempty(chil)
                parent.delete;
            end
        end

        % Clicked callback: Groups
        function GroupsClicked(app, event)
            node = event.InteractionInformation.Node;
            %parent=node.Parent;
            %tmpname=[parent.Text,'_',node.Text];
            if node.Parent==app.Groups
                groupname=node.Text;
                node=node.Children(1);
                tmpname=node.Text;
                csvname=[tmpname,'.csv'];
                if isempty(app.workpath1)
                    app.wfname1=tmpname;
                    app.Waveform1DropDown.Items={tmpname};
                    app.wavedata1=plotgraph(app,app.workpath2,tmpname,'b',app.UIAxes,app.ISI1);
                    if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                        updatemix(app);
                    end
                    app.groupname1.Value=groupname;
                elseif isempty(app.workpath2)
                    app.wfname1=tmpname;
                    kk=strcmp(app.Waveform1DropDown.Items,tmpname);
                    app.Waveform1DropDown.Value=app.Waveform1DropDown.Items(kk);
                    app.wavedata1=plotgraph(app,app.workpath1,tmpname,'b',app.UIAxes,app.ISI1);
                    if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                        updatemix(app);
                    end
                    app.groupname1.Value=groupname;
                elseif exist(fullfile(app.workpath2,csvname), 'file')==2 
                    app.wfname1=tmpname;
                    app.Waveform1DropDown.Items={tmpname};
                    app.wavedata1=plotgraph(app,app.workpath2,tmpname,'b',app.UIAxes,app.ISI1);
                    if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                        updatemix(app);
                    end 
                    app.groupname1.Value=groupname;
                else
                    app.wfname1=tmpname;
                    kk=strcmp(app.Waveform1DropDown.Items,tmpname);
                    app.Waveform1DropDown.Value=app.Waveform1DropDown.Items(kk);
                    app.wavedata1=plotgraph(app,app.workpath1,tmpname,'b',app.UIAxes,app.ISI1);
                    if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                        updatemix(app);
                    end
                    app.groupname1.Value=groupname;
                end
            else
                groupname=node.Parent.Text;
                tmpname=node.Text;
                csvname=[tmpname,'.csv'];
                if isempty(app.workpath1)
                    app.wfname2=tmpname;
                    kk=strcmp(app.Waveform2DropDown.Items,tmpname);
                    app.Waveform2DropDown.Value=app.Waveform2DropDown.Items(kk);
                    app.wavedata2=plotgraph(app,app.workpath2,tmpname,'r',app.UIAxes_3,app.ISI2);
                    if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                        updatemix(app);
                    end
                    app.groupname2.Value=groupname;
                elseif isempty(app.workpath2)
                    app.wfname2=tmpname;
                    app.Waveform2DropDown.Items={tmpname};
                    app.wavedata2=plotgraph(app,app.workpath1,tmpname,'r',app.UIAxes_3,app.ISI2);
                    if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                        updatemix(app);
                    end
                    app.groupname2.Value=groupname;
                elseif exist(fullfile(app.workpath2,csvname), 'file')==2 
                    app.wfname2=tmpname;
                    kk=strcmp(app.Waveform2DropDown.Items,tmpname);
                    app.Waveform2DropDown.Value=app.Waveform2DropDown.Items(kk);
                    app.wavedata2=plotgraph(app,app.workpath2,tmpname,'r',app.UIAxes_3,app.ISI2);
                    if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                        updatemix(app);
                    end
                    app.groupname2.Value=groupname;
                else
                    app.wfname2=tmpname;
                    app.Waveform2DropDown.Items={tmpname};
                    app.wavedata2=plotgraph(app,app.workpath1,tmpname,'r',app.UIAxes_3,app.ISI2);
                    if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                        updatemix(app);
                    end
                    app.groupname2.Value=groupname;
                end
            end
        end

        % Clicked callback: Unsorted
        function UnsortedClicked(app, event)
            node = event.InteractionInformation.Node;
            parent=node.Parent;
            tmpname=[node.Text];
            csvname=[tmpname,'.csv'];
            
                if isempty(app.workpath1)
                    app.wfname2=tmpname;
                    kk=strcmp(app.Waveform2DropDown.Items,tmpname);
                    app.Waveform2DropDown.Value=app.Waveform2DropDown.Items(kk);
                    app.wavedata2=plotgraph(app,app.workpath2,tmpname,'r',app.UIAxes_3,app.ISI2);
                    if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                        updatemix(app);
                    end
                elseif isempty(app.workpath2)
                    app.wfname2=tmpname;
                    app.Waveform2DropDown.Items={tmpname};
                    app.wavedata2=plotgraph(app,app.workpath1,tmpname,'r',app.UIAxes_3,app.ISI2);
                    if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                        updatemix(app);
                    end
                elseif exist(fullfile(app.workpath2,csvname), 'file')==2 
                    app.wfname2=tmpname;
                    kk=strcmp(app.Waveform2DropDown.Items,tmpname);
                    app.Waveform2DropDown.Value=app.Waveform2DropDown.Items(kk);
                    app.wavedata2=plotgraph(app,app.workpath2,tmpname,'r',app.UIAxes_3,app.ISI2);
                    if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                        updatemix(app);
                    end
                else
                    app.wfname2=tmpname;
                    app.Waveform2DropDown.Items={tmpname};
                    app.wavedata2=plotgraph(app,app.workpath1,tmpname,'r',app.UIAxes_3,app.ISI2);
                    if ~isempty(app.wavedata1) && ~isempty(app.wavedata2)
                        updatemix(app);
                    end
                end
            
        end

        % Menu selected function: ImportMenu
        function ImportMenuSelected(app, event)
             autoSelect = false;  % 如果为 true，自动选择 waveform 文件夹；为 false，手动选择
            if ~autoSelect
                [filename, filepath] = uigetfile({'*.mat', 'Mat Files (*.mat)'}, '选择数据文件');
                % 检查用户是否取消选择
                if isequal(filename, 0)
                    uiconfirm(app.UIFigure, 'User canceld the option!', 'Note');
                else
                    selectedFile = fullfile(filepath, filename);
                    OriData=load(selectedFile);
                    % 将匹配的变量名添加到 ListBox 中显示
                    updateImport(app,OriData);
                    uiconfirm(app.UIFigure, 'Load Data Done!', 'Note',"Options",'OK');
                end
            else
                selectedFile = fullfile(pwd,'combined/CombineDetail.mat')
                 OriData=load(selectedFile);
                    % 将匹配的变量名添加到 ListBox 中显示
                    updateImport(app,OriData);
                    uiconfirm(app.UIFigure, 'Load Data Done!', 'Note',"Options",'OK');
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: nowButton
        function nowButtonPushed(app, event)
            wavelist=[];
            parentNode=app.pcatree.Children;
            parentNodeName={parentNode.Text};

            tmpname=app.Waveform1DropDown.Value;
            trialname=['Channel',tmpname(end-2:end-1)];
            kk=strcmp(parentNodeName,trialname);
            pNode=parentNode(kk).Children;
            kk=strcmp({pNode.Text},tmpname);
            cNode1=pNode(kk);
            
            tmpname(9:12)=[];
            tmpname=[tmpname,'_wf'];
            wavelist{1}=tmpname;

            tmpname=app.Waveform2DropDown.Value;
            trialname=['Channel',tmpname(end-2:end-1)];
            kk=strcmp(parentNodeName,trialname);
            pNode=parentNode(kk).Children;
            kk=strcmp({pNode.Text},tmpname);
            cNode2=pNode(kk);
            tmpname(9:12)=[];
            tmpname=[tmpname,'_wf'];
            wavelist{2}=tmpname;
            plotpca(app,wavelist);
            
            app.pcatree.CheckedNodes=[cNode1 cNode2];

           
        end

        % Selection changed function: Unsorted
        function UnsortedSelectionChanged(app, event)
            selectedNodes = app.Unsorted.SelectedNodes;
            
        end

        % Double-clicked callback: Unsorted
        function UnsortedDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            
        end

        % Callback function: pcatree
        function pcatreeCheckedNodesChanged(app, event)
            checkedNodes = app.pcatree.CheckedNodes;
            if isempty(checkedNodes)
                cla(app.pcaplot)
            else
                li={checkedNodes.Text};
                li=li(~startsWith(li,'Channel'));
                for i=1:numel(li)
                    tmp=li{i};
                    tmp(9:12)=[];
                    tmp=[tmp,'_wf'];
                    li{i}=tmp;
                end
                plotpca(app,li);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.workdir1Menu = uimenu(app.UIFigure);
            app.workdir1Menu.MenuSelectedFcn = createCallbackFcn(app, @workdir1MenuSelected, true);
            app.workdir1Menu.Text = 'workdir1';
            app.workdir2Menu = uimenu(app.UIFigure);
            app.workdir2Menu.MenuSelectedFcn = createCallbackFcn(app, @workdir2MenuSelected, true);
            app.workdir2Menu.Text = 'workdir2';
            app.ImportMenu = uimenu(app.UIFigure);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMenuSelected, true);
            app.ImportMenu.Text = 'Import';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.ISI1 = uiaxes(app.UIFigure);
            title(app.ISI1, 'Title')
            xlabel(app.ISI1, 'X')
            ylabel(app.ISI1, 'Y')
            app.autocorr = uiaxes(app.UIFigure);
            title(app.autocorr, 'Title')
            xlabel(app.autocorr, 'X')
            ylabel(app.autocorr, 'Y')
            app.ISI2 = uiaxes(app.UIFigure);
            title(app.ISI2, 'Title')
            xlabel(app.ISI2, 'X')
            ylabel(app.ISI2, 'Y')
            app.pcaplot = uiaxes(app.UIFigure);
            title(app.pcaplot, 'Title')
            xlabel(app.pcaplot, 'X')
            ylabel(app.pcaplot, 'Y')
            app.Waveform2DropDownLabel = uilabel(app.UIFigure);
            app.Waveform2DropDownLabel.Text = 'Waveform2';
            app.Waveform2DropDown = uidropdown(app.UIFigure);
            app.Waveform2DropDown.Items = {};
            app.Waveform2DropDown.ValueChangedFcn = createCallbackFcn(app, @Waveform2DropDownValueChanged, true);
            app.Waveform2DropDown.Value = {};
            app.Groups = uitree(app.UIFigure);
            app.Groups.ClickedFcn = createCallbackFcn(app, @GroupsClicked, true);
            app.Groups.DoubleClickedFcn = createCallbackFcn(app, @GroupsDoubleClicked, true);
            app.Waveform1DropDownLabel = uilabel(app.UIFigure);
            app.Waveform1DropDownLabel.Text = 'Waveform1';
            app.Waveform1DropDown = uidropdown(app.UIFigure);
            app.Waveform1DropDown.Items = {};
            app.Waveform1DropDown.ValueChangedFcn = createCallbackFcn(app, @Waveform1DropDownValueChanged, true);
            app.Waveform1DropDown.Value = {};
            app.GroupEditFieldLabel = uilabel(app.UIFigure);
            app.GroupEditFieldLabel.Text = 'Group';
            app.groupname1 = uieditfield(app.UIFigure, 'text');
            app.groupname1.ValueChangedFcn = createCallbackFcn(app, @groupname1ValueChanged, true);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.AddButton_2 = uibutton(app.UIFigure, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.GroupEditField_2Label = uilabel(app.UIFigure);
            app.GroupEditField_2Label.Text = 'Group';
            app.groupname2 = uieditfield(app.UIFigure, 'text');
            app.groupname2.ValueChangedFcn = createCallbackFcn(app, @groupname2ValueChanged, true);
            app.Unsorted = uitree(app.UIFigure);
            app.Unsorted.SelectionChangedFcn = createCallbackFcn(app, @UnsortedSelectionChanged, true);
            app.Unsorted.ClickedFcn = createCallbackFcn(app, @UnsortedClicked, true);
            app.Unsorted.DoubleClickedFcn = createCallbackFcn(app, @UnsortedDoubleClicked, true);
            app.UnsortedLabel = uilabel(app.UIFigure);
            app.UnsortedLabel.Text = 'Unsorted';
            app.SortedGroupsLabel = uilabel(app.UIFigure);
            app.SortedGroupsLabel.Text = 'Sorted Groups';
            app.CombineOutputEditFieldLabel = uilabel(app.UIFigure);
            app.CombineOutputEditFieldLabel.Text = 'Combine Output';
            app.CombineOutputEditField = uieditfield(app.UIFigure, 'text');
            app.combineDir = uibutton(app.UIFigure, 'push');
            app.combineDir.ButtonPushedFcn = createCallbackFcn(app, @combineDirButtonPushed, true);
            app.combineDir.Text = 'Select Dir';
            app.combineOut = uibutton(app.UIFigure, 'push');
            app.combineOut.ButtonPushedFcn = createCallbackFcn(app, @combineOutButtonPushed, true);
            app.combineOut.Text = 'Select Dir';
            app.CombineFromEditFieldLabel = uilabel(app.UIFigure);
            app.CombineFromEditFieldLabel.Text = 'Combine From';
            app.CombineFromEditField = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.DatanameEditFieldLabel = uilabel(app.UIFigure);
            app.DatanameEditFieldLabel.Text = 'Data name';
            app.DatanameEditField = uieditfield(app.UIFigure, 'text');
            app.DatanameEditField.Value = 'data_sd';
            app.pcatree = uitree(app.UIFigure, 'checkbox');
            % Assign Checked Nodes
            app.pcatree.CheckedNodesChangedFcn = createCallbackFcn(app, @pcatreeCheckedNodesChanged, true);
            app.nowButton = uibutton(app.UIFigure, 'push');
            app.nowButton.ButtonPushedFcn = createCallbackFcn(app, @nowButtonPushed, true);
            app.nowButton.Text = 'now';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = neuroCombine

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end