classdef viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        WorkFileMenu              matlab.ui.container.Menu
        FreqBandButtonGroup       matlab.ui.container.ButtonGroup
        nogammaButton             matlab.ui.control.ToggleButton
        AllBandButton             matlab.ui.control.ToggleButton
        highgammaButton           matlab.ui.control.ToggleButton
        lowgammaButton            matlab.ui.control.ToggleButton
        betaButton                matlab.ui.control.ToggleButton
        alphaButton               matlab.ui.control.ToggleButton
        thetaButton               matlab.ui.control.ToggleButton
        deltaButton               matlab.ui.control.ToggleButton
        VerifyTimeEditField       matlab.ui.control.EditField
        VerifyTimeEditFieldLabel  matlab.ui.control.Label
        ButtonGroup               matlab.ui.container.ButtonGroup
        CancelAllButton           matlab.ui.control.ToggleButton
        SelectAllButton           matlab.ui.control.ToggleButton
        Panel                     matlab.ui.container.Panel
        AnalysisPanel             matlab.ui.container.Panel
        GCCheckBox                matlab.ui.control.CheckBox
        TimeFTACheckBox           matlab.ui.control.CheckBox
        FTACheckBox               matlab.ui.control.CheckBox
        TimeSTACheckBox           matlab.ui.control.CheckBox
        STACheckBox               matlab.ui.control.CheckBox
        TimeCoherenceCheckBox     matlab.ui.control.CheckBox
        CoherenceCheckBox         matlab.ui.control.CheckBox
        TimeSpectrumCheckBox      matlab.ui.control.CheckBox
        SpectrumCheckBox          matlab.ui.control.CheckBox
        ViewListListBox           matlab.ui.control.ListBox
        ViewListListBoxLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        OriData % Description
        SubNum;
        SelectName;
        viewIndx
    end
    
    methods (Access = private)
        
        function ViewData(app,name)
            app.Panel.Children.delete
            start_value=0.2;
            vindx=app.viewIndx;
            end_value=1;
            eval(['data=app.OriData.',name,';']);
            k=1;
            veryfyt=text2num(app,app.VerifyTimeEditField.Value);
            veryfyt=veryfyt(1);
            if app.SubNum==1
                i=1;j=1;
            elseif  app.SubNum==2
                i=1;j=2;
            elseif app.SubNum==3
                i=1;j=3;
            elseif app.SubNum==4
                i=2;j=2;
            elseif app.SubNum==5
                i=2;j=3;
            elseif app.SubNum==6
                i=2;j=3;
            elseif app.SubNum==7
                i=2;j=4;
            elseif app.SubNum==8
                i=2;j=4;
            elseif app.SubNum==9
                i=3;j=3;
            elseif app.SubNum==10
                i=2;j=5;
            elseif app.SubNum==11
                i=2;j=6;
            elseif app.SubNum==12
                i=2;j=6;
            else
                i=2;j=7;
            end
            
            if isfield(data,'spec')

            specf=data.spec.f;
            vindx=app.viewIndx;
            indx=specf>vindx(1) & specf<=vindx(2);
            specf=specf(indx);
            if app.SpectrumCheckBox.Value    
            ax=subplot(i,j,k,'Parent',app.Panel);
            figure;
            ax=gca;
            plot(ax,specf, data.spec.Ss(indx), 'k', 'LineWidth', 2);xlim(ax,vindx);xlabel(ax,'Frequency');ylabel(ax,'Power (Hz)');title (ax,'Spectrum SPK');
            k=k+1;
            
            ax=subplot(i,j,k,'Parent',app.Panel);
            figure;
            ax=gca;
            plot(ax,specf, 10*log10(data.spec.Sf(indx)), 'k', 'LineWidth', 2);xlim(ax,vindx);xlabel(ax,'Frequency');ylabel(ax,'Power (Hz)');title(ax,'Spectrum LFP');
            k=k+1;
            end

            if app.CoherenceCheckBox.Value
            ax=subplot(i,j,k,'Parent',app.Panel);
            figure;
            ax=gca;
            plot(ax,specf, data.spec.C(indx), 'k', 'LineWidth', 2);xlim(ax,vindx);xlabel(ax,'Frequency');ylabel(ax,'Coherence');title(ax,'Coherence');
            k=k+1;
            %待修改为百分比
            ax=subplot(i,j,k,polaraxes,'Parent',app.Panel);
            %figure;
            %ax=gca;
            %close all;
            figure;
            polaraxes;
            ax=gca;
            polarhistogram(ax,data.spec.Phi(indx),'Normalization','probability');title(ax,'Phase of Coherence');
            k=k+1;
            end
            end

            if isfield(data,'tspec')
            tspect=data.tspec.t;
            tspecf=data.tspec.f;
            indx=tspecf>vindx(1) & tspecf<=vindx(2);
            tspecf=tspecf(indx);
            if app.TimeSpectrumCheckBox.Value
            ax=subplot(i,j,k,'Parent',app.Panel);
            hold(ax,"on");
            figure;
            ax=gca;
            imagesc(ax,tspect+veryfyt,tspecf,(10*log10(data.tspec.Ss)'));colorbar(ax);xlabel(ax,'time(s)');ylabel(ax,'Frequency(Hz)');title (ax,'Time-Spectrum SPK');
            line(ax,[0.2,0.2],[0 120], 'Color', 'red', 'LineWidth', 2);
            line(ax,[1,1],[0 120], 'Color', 'blue', 'LineWidth', 2);
            hold(ax,"off");
            k=k+1;
            ax=subplot(i,j,k,'Parent',app.Panel);
            hold(ax,"on");
            figure;
            ax=gca;
            imagesc(ax,tspect+veryfyt,tspecf,10*log10(data.tspec.Sf(:,indx)'));colorbar(ax);xlabel(ax,'time(s)');ylabel(ax,'Frequency(Hz)');title (ax,'Time-Spectrum LFP');
            line(ax,[start_value, start_value], ylim, 'Color', 'red', 'LineWidth', 2);
            line(ax,[end_value, end_value], ylim, 'Color', 'blue', 'LineWidth', 2);
            hold(ax,"off");
            k=k+1;
            end
            if app.TimeCoherenceCheckBox.Value
            ax=subplot(i,j,k,'Parent',app.Panel);
            hold(ax,"on");
            figure;
            ax=gca;
            imagesc(ax,tspect+veryfyt,tspecf,(data.tspec.C(:,indx))');colorbar(ax);xlabel(ax,'time(s)');ylabel(ax,'Frequency(Hz)');title (ax,'Time-Coherence');
            line(ax,[start_value, start_value], ylim, 'Color', 'red', 'LineWidth', 2);
            line(ax,[end_value, end_value], ylim, 'Color', 'blue', 'LineWidth', 2);
            hold(ax,"off");
            k=k+1;
            ax=subplot(i,j,k,'Parent',app.Panel);
            hold(ax,"on");
            figure;
            ax=gca;
            imagesc(ax,tspect+veryfyt,tspecf,(data.tspec.Phi(:,indx))');colorbar(ax);xlabel(ax,'time(s)');ylabel(ax,'Frequency(Hz)');title (ax,'Time-Phase of Coherence');
            line(ax,[start_value, start_value], ylim, 'Color', 'red', 'LineWidth', 2);
            line(ax,[end_value, end_value], ylim, 'Color', 'blue', 'LineWidth', 2);
            hold(ax,"off");
            k=k+1;
            end

            end
            
            if isfield(data,'indepSTA')
            stas=data.indepSTA.sta;
            stat=data.indepSTA.t;
            if app.STACheckBox.Value
            ax=subplot(i,j,k,'Parent',app.Panel);
            figure;
            ax=gca;
            plot(ax,stat,mean(stas)-mean(stas(:)) ,'k', 'LineWidth', 2);xlabel(ax,'Time (s)');ylabel(ax,'Voltage (mV)');title(ax,'STA');
            k=k+1;
            end
            end
            if isfield(data,'tSTA')
            if app.TimeSTACheckBox.Value
            ax=subplot(i,j,k,'Parent',app.Panel);
            hold(ax,"on");
            figure;
            ax=gca;
            imagesc(ax,data.tSTA.tc+veryfyt,data.tSTA.tau,squeeze(data.tSTA.sta)');xlabel(ax,'time (s)');ylabel(ax,'lag(s)');title(ax,['Time-STA']);colorbar(ax);
                        line(ax,[start_value, start_value], ylim, 'Color', 'red', 'LineWidth', 2);
            line(ax,[end_value, end_value], ylim, 'Color', 'blue', 'LineWidth', 2);
            hold(ax,"off");
            k=k+1;
            end
            end
            
            if isfield(data,'indepFTA')
            if app.FTACheckBox.Value
            ax=subplot(i,j,k,'Parent',app.Panel);
            figure;
            ax=gca;
            plot(ax,data.indepFTA.phi, mean(data.indepFTA.fta, 1), 'k', 'LineWidth', 2);xlabel(ax,'Phase');ylabel(ax,'FTA');title(ax,'FTA');xlim(ax,[-pi pi]);ylim(ax,[0 0.05]);
            k=k+1;
            end
            end
            if isfield(data,'tFTA')
            if app.TimeFTACheckBox.Value
            ax=subplot(i,j,k,'Parent',app.Panel);
            hold(ax,"on");
            figure;
            ax=gca;
            imagesc(ax,data.tFTA.tc+veryfyt,data.tFTA.phi,squeeze(data.tFTA.fta)');xlabel(ax,'time (s)');ylabel(ax,'phase');title(ax,['Time-FTA']);colorbar(ax);colormap(ax,hot);
            line(ax,[start_value, start_value], ylim, 'Color', 'red', 'LineWidth', 2);
            line(ax,[end_value, end_value], ylim, 'Color', 'blue', 'LineWidth', 2);
            hold(ax,"off");
            k=k+1;
            end
            end
            
            if isfield(data,'GC')
            if app.GCCheckBox.Value
            gctt=data.GC.t;
            gcf=data.GC.f;
            GCs2p=data.GC.s2p;
            GCp2s=data.GC.p2s;
            indx=gcf>vindx(1) & gcf<=vindx(2);
            GCs2p=GCs2p(:,indx);
            GCp2s=GCp2s(:,indx);
            gcf=gcf(indx);
            figure;
            ax=subplot(1,2,1);
            %ax=subplot(i,j,k,'Parent',app.Panel);
            hold(ax,"on");
            plot(ax,gctt+veryfyt, sum(GCs2p,2));title(ax,['Time GC']);xlabel(ax,'time (s)');ylabel(ax,'GC');hold(ax,'on');
            plot(ax,gctt+veryfyt, sum(GCp2s,2));title(ax,['Time GC']);xlabel(ax,'time (s)');ylabel(ax,'GC');
            line(ax,[start_value, start_value], ylim, 'Color', 'red', 'LineWidth', 2);
            line(ax,[end_value, end_value], ylim, 'Color', 'blue', 'LineWidth', 2);
            legend(ax,'s2p','p2s','onset','offset');
            hold(ax,"off");
            k=k+1;
            ax=subplot(1,2,2);
            %ax=subplot(i,j,k,'Parent',app.Panel);
            plot(ax,gcf, sum(GCs2p,1));title(ax,['Freq GC']);xlabel(ax,'Frequency (Hz)');ylabel(ax,'GC');hold(ax,'on');
            plot(ax,gcf, sum(GCp2s,1));title(ax,['Freq GC']);xlabel(ax,'Frequency (Hz)');ylabel(ax,'GC');legend(ax,'s2p','p2s');hold(ax,'off');
            end
            end
        end
        
        
        function cancelall(app)
            app.GCCheckBox.Value=0;
            app.CoherenceCheckBox.Value=0;
            app.FTACheckBox.Value=0;
            app.STACheckBox.Value=0;
            app.SpectrumCheckBox.Value=0;
            app.TimeCoherenceCheckBox.Value=0;
            app.TimeFTACheckBox.Value=0;
            app.TimeSTACheckBox.Value=0;
            app.TimeSpectrumCheckBox.Value=0;
            app.SubNum=0;
        end
        
        function selectall(app)
            app.GCCheckBox.Value=1;
            app.CoherenceCheckBox.Value=1;
            app.FTACheckBox.Value=1;
            app.STACheckBox.Value=1;
            app.SpectrumCheckBox.Value=1;
            app.TimeCoherenceCheckBox.Value=1;
            app.TimeFTACheckBox.Value=1;
            app.TimeSTACheckBox.Value=1;
            app.TimeSpectrumCheckBox.Value=1;
            app.SubNum=14;
        end

        function numArray = text2num(app,str)
            str = strrep(str, '[', ''); % 去除左方括号
            str = strrep(str, ']', ''); % 去除右方括号
            % 使用 strsplit 函数分割字符串
            strArray = strsplit(str, ',');
            % 将字符串数组转换为数值数组
            numArray = str2double(strArray);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: WorkFileMenu
        function WorkFileMenuSelected(app, event)
            [filename, filepath] = uigetfile({'*.mat', 'Mat Files (*.mat)'}, '选择数据文件');
            % 检查用户是否取消选择
            if isequal(filename, 0)
                uiconfirm(app.UIFigure, 'User canceld the option!', 'Note');
            else
                selectedFile = fullfile(filepath, filename);
                app.OriData=load(selectedFile);
                vars = fieldnames(app.OriData);
                % 将匹配的变量名添加到 ListBox 中显示
                app.ViewListListBox.Items = vars;
                uiconfirm(app.UIFigure, 'Load Data Done!', 'Note',"Options",'OK');
                
                selectall(app);
                app.SubNum=14;
                app.viewIndx=[0 30]
            end
        end

        % Clicked callback: ViewListListBox
        function ViewListListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
        end

        % Value changed function: SpectrumCheckBox
        function SpectrumCheckBoxValueChanged(app, event)
            value = app.SpectrumCheckBox.Value;
            if value 
                app.SubNum=app.SubNum+2;
            else 
                app.SubNum=app.SubNum-2;
            end
            ViewData(app,app.SelectName);
        end

        % Value changed function: TimeSpectrumCheckBox
        function TimeSpectrumCheckBoxValueChanged(app, event)
            value = app.TimeSpectrumCheckBox.Value;
            if value 
                app.SubNum=app.SubNum+2;
            else 
                app.SubNum=app.SubNum-2;
            end
            ViewData(app,app.SelectName);
        end

        % Value changed function: CoherenceCheckBox
        function CoherenceCheckBoxValueChanged(app, event)
            value = app.CoherenceCheckBox.Value;
            if value 
                app.SubNum=app.SubNum+2;
            else 
                app.SubNum=app.SubNum-2;
            end
            ViewData(app,app.SelectName);
        end

        % Value changed function: TimeCoherenceCheckBox
        function TimeCoherenceCheckBoxValueChanged(app, event)
            value = app.TimeCoherenceCheckBox.Value;
            if value 
                app.SubNum=app.SubNum+2;
            else 
                app.SubNum=app.SubNum-2;
            end
            ViewData(app,app.SelectName);
        end

        % Value changed function: STACheckBox
        function STACheckBoxValueChanged(app, event)
            value = app.STACheckBox.Value;
            if value 
                app.SubNum=app.SubNum+1;
            else 
                app.SubNum=app.SubNum-1;
            end
            ViewData(app,app.SelectName);
        end

        % Value changed function: TimeSTACheckBox
        function TimeSTACheckBoxValueChanged(app, event)
            value = app.TimeSTACheckBox.Value;
            if value 
                app.SubNum=app.SubNum+1;
            else 
                app.SubNum=app.SubNum-1;
            end
            ViewData(app,app.SelectName);
        end

        % Value changed function: FTACheckBox
        function FTACheckBoxValueChanged(app, event)
            value = app.FTACheckBox.Value;
            if value 
                app.SubNum=app.SubNum+1;
            else 
                app.SubNum=app.SubNum-1;
            end
            ViewData(app,app.SelectName);
        end

        % Value changed function: TimeFTACheckBox
        function TimeFTACheckBoxValueChanged(app, event)
            value = app.TimeFTACheckBox.Value;
            if value 
                app.SubNum=app.SubNum+1;
            else 
                app.SubNum=app.SubNum-1;
            end
            ViewData(app,app.SelectName);
        end

        % Value changed function: GCCheckBox
        function GCCheckBoxValueChanged(app, event)
            value = app.GCCheckBox.Value;
            if value 
                app.SubNum=app.SubNum+2;
            else 
                app.SubNum=app.SubNum-2;
            end
            ViewData(app,app.SelectName);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton==app.SelectAllButton
                selectall(app);
            else 
                cancelall(app);
            end
            ViewData(app,app.SelectName);
        end

        % Double-clicked callback: ViewListListBox
        function ViewListListBoxDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            name=app.ViewListListBox.Value;
            app.SelectName=name;
            ViewData(app,name);
        end

        % Selection changed function: FreqBandButtonGroup
        function FreqBandButtonGroupSelectionChanged(app, event)
            selectedButton = app.FreqBandButtonGroup.SelectedObject;
                if selectedButton==app.deltaButton
                    app.viewIndx=[0 4];
                elseif selectedButton==app.thetaButton
                    app.viewIndx=[5 8];   
                elseif selectedButton==app.alphaButton
                    app.viewIndx=[9 12];
                elseif  selectedButton==app.betaButton
                    app.viewIndx=[13 30];
                elseif  selectedButton==app.highgammaButton
                    app.viewIndx=[70 120];
                elseif  selectedButton==app.lowgammaButton
                    app.viewIndx=[31 70];
                elseif selectedButton==app.AllBandButton
                    app.viewIndx=[0 30];
                elseif selectedButton==app.nogammaButton
                    app.viewIndx=[0 30];
                end
                ViewData(app,app.SelectName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WorkFileMenu = uimenu(app.UIFigure);
            app.WorkFileMenu.MenuSelectedFcn = createCallbackFcn(app, @WorkFileMenuSelected, true);
            app.WorkFileMenu.Text = 'WorkFile';
            app.ViewListListBoxLabel = uilabel(app.UIFigure);
            app.ViewListListBoxLabel.Text = 'View  List';
            app.ViewListListBox = uilistbox(app.UIFigure);
            app.ViewListListBox.Items = {};
            app.ViewListListBox.ClickedFcn = createCallbackFcn(app, @ViewListListBoxClicked, true);
            app.ViewListListBox.DoubleClickedFcn = createCallbackFcn(app, @ViewListListBoxDoubleClicked, true);
            app.ViewListListBox.Value = {};
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis Panel';
            app.SpectrumCheckBox = uicheckbox(app.AnalysisPanel);
            app.SpectrumCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpectrumCheckBoxValueChanged, true);
            app.SpectrumCheckBox.Text = 'Spectrum';
            app.TimeSpectrumCheckBox = uicheckbox(app.AnalysisPanel);
            app.TimeSpectrumCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeSpectrumCheckBoxValueChanged, true);
            app.TimeSpectrumCheckBox.Text = 'Time-Spectrum';
            app.CoherenceCheckBox = uicheckbox(app.AnalysisPanel);
            app.CoherenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoherenceCheckBoxValueChanged, true);
            app.CoherenceCheckBox.Text = 'Coherence';
            app.TimeCoherenceCheckBox = uicheckbox(app.AnalysisPanel);
            app.TimeCoherenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeCoherenceCheckBoxValueChanged, true);
            app.TimeCoherenceCheckBox.Text = 'Time-Coherence';
            app.STACheckBox = uicheckbox(app.AnalysisPanel);
            app.STACheckBox.ValueChangedFcn = createCallbackFcn(app, @STACheckBoxValueChanged, true);
            app.STACheckBox.Text = 'STA';
            app.TimeSTACheckBox = uicheckbox(app.AnalysisPanel);
            app.TimeSTACheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeSTACheckBoxValueChanged, true);
            app.TimeSTACheckBox.Text = 'Time-STA';
            app.FTACheckBox = uicheckbox(app.AnalysisPanel);
            app.FTACheckBox.ValueChangedFcn = createCallbackFcn(app, @FTACheckBoxValueChanged, true);
            app.FTACheckBox.Text = 'FTA';
            app.TimeFTACheckBox = uicheckbox(app.AnalysisPanel);
            app.TimeFTACheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeFTACheckBoxValueChanged, true);
            app.TimeFTACheckBox.Text = 'Time-FTA';
            app.GCCheckBox = uicheckbox(app.AnalysisPanel);
            app.GCCheckBox.ValueChangedFcn = createCallbackFcn(app, @GCCheckBoxValueChanged, true);
            app.GCCheckBox.Text = 'GC';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.SelectAllButton = uitogglebutton(app.ButtonGroup);
            app.SelectAllButton.Text = 'Select All';
            app.SelectAllButton.Value = true;
            app.CancelAllButton = uitogglebutton(app.ButtonGroup);
            app.CancelAllButton.Text = 'Cancel All';
            app.VerifyTimeEditFieldLabel = uilabel(app.UIFigure);
            app.VerifyTimeEditFieldLabel.Text = 'Verify Time';
            app.VerifyTimeEditField = uieditfield(app.UIFigure, 'text');
            app.VerifyTimeEditField.Value = '[-0.5,1.5]';
            app.FreqBandButtonGroup = uibuttongroup(app.UIFigure);
            app.FreqBandButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FreqBandButtonGroupSelectionChanged, true);
            app.FreqBandButtonGroup.Title = 'Freq Band';
            app.deltaButton = uitogglebutton(app.FreqBandButtonGroup);
            app.deltaButton.Text = 'delta (<4Hz)';
            app.thetaButton = uitogglebutton(app.FreqBandButtonGroup);
            app.thetaButton.Text = 'theta (5-8Hz)';
            app.alphaButton = uitogglebutton(app.FreqBandButtonGroup);
            app.alphaButton.Text = 'alpha (9-12Hz)';
            app.betaButton = uitogglebutton(app.FreqBandButtonGroup);
            app.betaButton.Text = 'beta （13-30Hz）';
            app.lowgammaButton = uitogglebutton(app.FreqBandButtonGroup);
            app.lowgammaButton.Text = 'low-gamma （31-70Hz）';
            app.highgammaButton = uitogglebutton(app.FreqBandButtonGroup);
            app.highgammaButton.Text = 'high-gamma （31-70Hz）';
            app.AllBandButton = uitogglebutton(app.FreqBandButtonGroup);
            app.AllBandButton.Text = 'All Band';
            app.AllBandButton.Value = true;
            app.nogammaButton = uitogglebutton(app.FreqBandButtonGroup);
            app.nogammaButton.Text = 'Nogamma';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end